classdef circles < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        GenerationTab              matlab.ui.container.Tab
        GeneratePanel              matlab.ui.container.Panel
        SeedEditFieldLabel         matlab.ui.control.Label
        SeedEditField              matlab.ui.control.NumericEditField
        Length_sEditFieldLabel     matlab.ui.control.Label
        Length_sEditField          matlab.ui.control.NumericEditField
        ColorTable                 matlab.ui.control.Table
        GenerateCirclesButton      matlab.ui.control.Button
        ViewerPanel                matlab.ui.container.Panel
        TimeStepEditFieldLabel     matlab.ui.control.Label
        TimeStepEditField          matlab.ui.control.NumericEditField
        SmallAxes                  matlab.ui.control.UIAxes
        SliderLabel                matlab.ui.control.Label
        TimeStepSlider             matlab.ui.control.Slider
        RunPanel                   matlab.ui.container.Panel
        StartButton                matlab.ui.control.Button
        trigFuncSelect             matlab.ui.control.Switch
        ShowCirclesCheckBox        matlab.ui.control.CheckBox
        TrigandSoundCheckBox       matlab.ui.control.CheckBox
        SoundLengthEditFieldLabel  matlab.ui.control.Label
        SoundLengthEditField       matlab.ui.control.NumericEditField
        CircleLifeEditFieldLabel   matlab.ui.control.Label
        CircleLifeEditField        matlab.ui.control.NumericEditField
        CircleDataFilesPanel       matlab.ui.container.Panel
        LoadEditField              matlab.ui.control.EditField
        LoadButton                 matlab.ui.control.Button
        SaveEditField              matlab.ui.control.EditField
        SaveButton                 matlab.ui.control.Button
        CirclesTable               matlab.ui.control.Table
        AudioTab                   matlab.ui.container.Tab
        SubjectViewTab             matlab.ui.container.Tab
        MainAxes                   matlab.ui.control.UIAxes
    end


    properties (Access = public)      
        circleArray 
        I    %static all black image
        imRows %number of rows in the image I, initialized at startup
        imColumns % number of columns in the image I. initialized at startup
        timeStep % current timestep
        updateTimer
    
        
    end
    
    properties (Constant)
        deadTime = 5;    %time to wait before restarting the app for another run (in timer ticks)
        opacityLevels = 9; %number of opacity levels must always be an odd number
        tStepLength = 0.1; %seconds per timestep when timer is running, if it's too low the program can lag and cause circles to not be drawn properly
        
        supportedColors = {'blue', 'green', 'red', 'cyan', 'magenta', 'black', 'white'} %colors supported by the insertShape
                                                                                        %any rgb values can be used but these are the 
                                                                                        %only colors which are given specific names
        
        %index of colums in the main table
        %The columns in the main table can be reordered
        %If they are these values need to be changed to reflect the changes
        %CentreX CentreY and Radius columns must be together,in that order, for the circle drawing function to work properly
        x_ind = 1;
        y_ind = 2;
        rad_ind = 3;
        start_ind = 5;
        color_ind = 4;
        trig_ind = 6;
        
        
    end 

    methods (Access = private)
    
        %this function is used to display the circles for a given tStep on the given axes using data in app.circleArray
        function updateCircles(app, axes, tStep)
            

            maxOpLevel = app.opacityLevels/2 + 0.5; %the centre opacity level is the highest
            J = app.I;

            for opacity =1:maxOpLevel
                %calculating opacity levels on scale of 0 to 1 for insert shape function
                % depends on distance between current opacitylevel and max opacitylevel abs(maxOpLevel - opacity)
                % increment between consecutive opacity levels  is 1/maxOpLevel
                % if distance is 0 opacity = 1 -> maximum opacity
                % if distance is 1 opacity = 1 - 1/maxOpLevel -> slightly less opacity
                % etc.
                opacityNum = 1 - abs(maxOpLevel - opacity)/maxOpLevel; 
                
                circleToDraw = app.circleArray{tStep,opacity,1}; % this grabs the geometry of the circles: x,y and radius
                
                colors = app.circleArray{tStep,opacity,2}; % this grabs the color of each circle
                
                if circleToDraw %if circleToDraw is empty skip insertShape to avoid extra processing
                    J = insertShape(J,'FilledCircle',circleToDraw,'Color',colors,'Opacity', opacityNum);
                    % insertShape is a slow function, limiting the amount of calls
                    % to insertShape significantly increases the speed of the program
                end


            end
            
        imshow(J,'Parent',axes);
          
            
        end
        
        %this is used to restart the timer after the final timestep has been reached
        function restart(app)
            app.timeStep = 0;
            app.TabGroup.SelectedTab = app.GenerationTab;
            
        end
        
        %this function is used to randomly generate circles
        function circleList = randomCircles(app, numCircles, numTimeSteps, maxLength)
            %generate matrix with 5 columns and numCircles rows
            %column 1  = horizontal coordinate of centre
            %column 2 = vertical coordinate of centre
            %column 3 = radius
            %column 4 = timeStep to appear
            %column 5 = timeStep to disappear
 
            circleList = zeros(numCircles,5);
            
            %radius from 50 to 100
            circleList(:,app.rad_ind) = round(rand(numCircles,1)*50 + 50);
            
            %x centre point from radius to numRows-radius
            %total range is length numRows - 2*radius
            %min value is radius
            circleList(:,app.x_ind) = round(rand(numCircles,1).*(app.imColumns - 2*circleList(:,app.rad_ind))+circleList(:,app.rad_ind));
            
            %y centre point from radius to numRows-radius
            circleList(:,app.y_ind) = round(rand(numCircles,1).*(app.imRows - 2*circleList(:,app.rad_ind))+circleList(:,app.rad_ind));
            
            %start time from 1 to numTimeSteps
            circleList(:,app.start_ind) = round(rand(numCircles,1)*(numTimeSteps -1) + 1);
            %length of 5 to maxLength
            circleList(:,5) = maxLength + circleList(:,app.start_ind);
            
           
        end 
        
        %this function is used to reorganize data in the CirclesUITable into a format 
        %more suitable for the insertShape function
        %this helps to speed up processing time
        
        function circleTimeline = circThroughTime(app)
            
            circles = app.CirclesTable.Data;
            
            maxOpLevel = app.opacityLevels/2 + 0.5; %max opacity occurs at this level
            
            lastTimeStep = app.maxTimestep(); %last timestep of any circle possible
            maxLength = app.circleLife();    % how long a circle will appear for
            circleTimeline = cell(lastTimeStep,maxOpLevel,2); %the desired cell array, empty
            
            
            for tStep = 1:lastTimeStep
               %this calculates current state of opacity
               %based on the fraction of time since the circle appeared of its total live
               opacity  = round((tStep - cell2mat(circles(:,app.start_ind)))/maxLength*app.opacityLevels + 0.5); %opacity from 1 to num opacity levels, evenly spaced across the entire circle life
               
               %opacity will be determined by the distance from max opacity level
               opacity = maxOpLevel-abs(maxOpLevel-opacity);
               
               for thisOpac = 1:maxOpLevel
                   %place circle geometries at given timestep and opacity
                   circleTimeline{tStep, thisOpac,1} = cell2mat(circles(opacity == thisOpac,app.x_ind:app.rad_ind));
                   
                   %place circle colors at given timestep and opacity
                   circleTimeline{tStep, thisOpac,2} = circles(opacity == thisOpac,app.color_ind);
                   
               end
               
               
            end


        end
        
        function trigFunc(app, trig)
            %to be implemented
            %the parameter trig represents the trigger that is required to be sent
            % when this function is called
            
        end
        
        %this function plays a single frequency beep at 200Hz times the value of tone
        %can modify this function to play a set of sound clips instead
        function soundFunc(app,tone)
            
            sound_singletone(tone*200,0.05)
       
        end
        
        %returns the final timestep in a single run
        function tMax = maxTimestep(app)
            tMax = max(cell2mat(app.CirclesTable.Data(:,app.start_ind))) +app.circleLife()+app.deadTime;
        end
        
        %returns the how long a circle will appear in number timesteps
        function life = circleLife(app)
            life = round(app.CircleLifeEditField.Value/app.tStepLength);
        end
        
        %the function called by the timer at each tick
        function timerFunc(app)
            %update timestep
            app.timeStep = app.timeStep + 1;
            %activate sound and/or trigger
            app.callTrigAndSound(app.timeStep)
            
            %update circles
            if app.ShowCirclesCheckBox.Value
                app.updateCircles(app.MainAxes, app.timeStep)
      
            end
            
            
        end
        
        %passes the parameters to the trigFunc and soundFunc at each timestep
        function callTrigAndSound(app,tStep)
            
            
            trigs = [app.CirclesTable.Data{:,app.trig_ind}];
            trigToUse = trigs([app.CirclesTable.Data{:,app.start_ind}] == tStep);
            
            if trigToUse
                for i =1:size(trigToUse)
                    if app.TrigandSoundCheckBox.Value
                        %send trig and play sound
                        app.soundFunc(trigToUse(i));
                        app.trigFunc(trigToUse(i));
                    elseif app.trigFuncSelect.Value == 'Sound'
                        %play sound only
                        app.soundFunc(trigToUse(i))
                    else
                        %send trig only
                        app.trigFunc(trigToUse(i));
                    end
                end
            end
            
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
      
            app.I = imread('AllBlack.png');
            imshow(app.I,'Parent',app.MainAxes)
            imshow(app.I,'Parent', app.SmallAxes)
            
            [app.imRows, app.imColumns] = size(app.I);
            app.imColumns = app.imColumns/3; %by default matlab treats images as 3 seperate images, each for R,B and G laid side by side
                                             %this effectively triples the size of the matrix compared to actual image size so divide by 3 to correct
            
            app.timeStep = 0;
            
            app.CirclesTable.Data =     csv2cell('lastCircles.csv','fromfile');
            
            %csv2cell stores all read values as strings
            %still need to convert numbers to number types
            app.CirclesTable.Data(:,app.x_ind:app.rad_ind) = num2cell(str2double(app.CirclesTable.Data(:,app.x_ind:app.rad_ind)));
            app.CirclesTable.Data(:,app.start_ind) = num2cell(str2double(app.CirclesTable.Data(:,app.start_ind)));
            app.CirclesTable.Data(:,app.trig_ind) = num2cell(str2double(app.CirclesTable.Data(:,app.trig_ind)));
            app.CirclesTableCellEdit(); % this callback is only called automatically when user changes data, not the program
                                        % need to call it manually here
            
            app.ColorTable.Data = csv2cell('lastColors.csv','fromfile');
            app.ColorTable.Data(:,2) = num2cell(str2double(app.ColorTable.Data(:,2)));


        end

        % Value changed function: CircleLifeEditField
        function CircleLifeEditFieldValueChanged(app, event)
            app.circleArray = app.circThroughTime();
        end

        % Cell edit callback: CirclesTable
        function CirclesTableCellEdit(app, event)
                
            app.TimeStepSlider.Limits = [1 app.maxTimestep()];
            app.circleArray = app.circThroughTime();
            
        end

        % Button pushed function: GenerateCirclesButton
        function GenerateCirclesButtonPushed(app, event)
                tSteps = round(app.Length_sEditField.Value/app.tStepLength);
                circTSpan = app.circleLife();
                
                rng(app.SeedEditField.Value); %useful for debugging because random numbers are consistent for a given seed
                [rows dummy] = size(app.ColorTable.Data);
                app.CirclesTable.Data = cell(sum(cell2mat(app.ColorTable.Data(:,2))), app.trig_ind); %calculate the sum of all colors as number of rows
                
                nextRow = 1;
                
                for i = 1:rows
                    %looping through each of the possible colors/triggers
                    color = app.ColorTable.Data{i,1};
                    numColor = app.ColorTable.Data{i,2};
                    trig = app.ColorTable.Data{i,3};
                    circles = app.randomCircles(numColor, tSteps,circTSpan);
                    
                    %populating UI Table with new data
                    app.CirclesTable.Data(nextRow:nextRow+numColor-1,app.x_ind:app.rad_ind)= num2cell(circles(:,app.x_ind:app.rad_ind));
                    app.CirclesTable.Data(nextRow:nextRow+numColor-1,app.start_ind)= num2cell(circles(:,app.start_ind));
                    app.CirclesTable.Data(nextRow:nextRow+numColor-1,app.color_ind) = {color};
                    app.CirclesTable.Data(nextRow:nextRow+numColor-1,app.trig_ind) = {[trig]};
                    
                    nextRow = nextRow + numColor;
                end
               
                
                app.CirclesTableCellEdit(); % this callback is only called automatically when user changes data, not the program
                                            % need to call it manually here


        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.CirclesTable.Data =     csv2cell(app.LoadEditField.Value,'fromfile');
            
            %csv2cell stores all read values as strings
            %still need to convert numbers to numbers
            app.CirclesTable.Data(:,app.x_ind:app.rad_ind) = num2cell(str2double(app.CirclesTable.Data(:,app.x_ind:app.rad_ind)));
            app.CirclesTable.Data(:,app.start_ind) = num2cell(str2double(app.CirclesTable.Data(:,app.start_ind)));
            app.CirclesTable.Data(:,app.trig_ind) = num2cell(str2double(app.CirclesTable.Data(:,app.trig_ind)));
            app.CirclesTableCellEdit(); 
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
             cell2csv(app.SaveEditField.Value, app.CirclesTable.Data);       
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
           
            if app.timeStep == 0
            
               
                app.circleArray = app.circThroughTime();
              
                
                app.updateTimer = timer('Period',app.tStepLength,...
                    'ExecutionMode', 'fixedRate', ...
                    'TasksToExecute', app.maxTimestep());
                app.updateTimer.TimerFcn = @(~,~)app.timerFunc;
                app.updateTimer.StopFcn = @(~,~)app.restart;
                
                start(app.updateTimer);
                
                if app.ShowCirclesCheckBox.Value
                    app.TabGroup.SelectedTab = app.SubjectViewTab;
                end
                
            end
            
        end

        % Value changed function: TimeStepEditField
        function TimeStepEditFieldValueChanged(app, event)
            tStep = abs(round(app.TimeStepEditField.Value)); %prevent negative and decimal inputs            
            app.TimeStepSlider.Value = tStep;
            app.TimeStepSliderValueChanged();
        end

        % Value changed function: TimeStepSlider
        function TimeStepSliderValueChanged(app, event)
            tStep = round(app.TimeStepSlider.Value);
            app.TimeStepSlider.Value = tStep;
            app.TimeStepEditField.Value = tStep;
            
            app.updateCircles(app.SmallAxes, tStep);
            
        end

        % Value changing function: TimeStepSlider
        function TimeStepSliderValueChanging(app, event)
            tStep = round(event.Value);
            app.TimeStepEditField.Value = tStep;
            app.callTrigAndSound(tStep);
            app.updateCircles(app.SmallAxes, tStep);
        end

        % Value changed function: TrigandSoundCheckBox
        function TrigandSoundCheckBoxValueChanged(app, event)
            value = app.TrigandSoundCheckBox.Value;
            if value
                app.trigFuncSelect.Enable = 'off'; %both enabled no need to select
            else
                app.trigFuncSelect.Enable = 'on';
            end 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %save all UI table data on close
            cell2csv('lastCircles.csv', app.CirclesTable.Data);
            cell2csv('lastColors.csv', app.ColorTable.Data);
            
            delete(app);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GenerationTab = uitab(app.TabGroup);
            app.GenerationTab.Title = 'Generation';
            app.GeneratePanel = uipanel(app.GenerationTab);
            app.GeneratePanel.Title = 'Generate';
            app.SeedEditFieldLabel = uilabel(app.GeneratePanel);
            app.SeedEditFieldLabel.Text = 'Seed';
            app.SeedEditField = uieditfield(app.GeneratePanel, 'numeric');
            app.SeedEditField.Value = 10;
            app.Length_sEditFieldLabel = uilabel(app.GeneratePanel);
            app.Length_sEditFieldLabel.Text = 'Length_(s)';
            app.Length_sEditField = uieditfield(app.GeneratePanel, 'numeric');
            app.Length_sEditField.Value = 5;
            app.ColorTable = uitable(app.GeneratePanel);
            app.ColorTable.ColumnName = {'Color '; 'Number'; 'Trigger'};
            app.ColorTable.RowName = {};
            app.ColorTable.ColumnEditable = true;
            app.GenerateCirclesButton = uibutton(app.GeneratePanel, 'push');
            app.GenerateCirclesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateCirclesButtonPushed, true);
            app.GenerateCirclesButton.Text = 'Generate Circles';
            app.ViewerPanel = uipanel(app.GenerationTab);
            app.ViewerPanel.Title = 'Viewer';
            app.TimeStepEditFieldLabel = uilabel(app.ViewerPanel);
            app.TimeStepEditFieldLabel.Text = 'TimeStep';
            app.TimeStepEditField = uieditfield(app.ViewerPanel, 'numeric');
            app.TimeStepEditField.ValueChangedFcn = createCallbackFcn(app, @TimeStepEditFieldValueChanged, true);
            app.TimeStepEditField.Value = 1;
            app.SmallAxes = uiaxes(app.ViewerPanel);
            app.SliderLabel = uilabel(app.ViewerPanel);
            app.SliderLabel.Text = 'Slider';
            app.TimeStepSlider = uislider(app.ViewerPanel);
            app.TimeStepSlider.ValueChangedFcn = createCallbackFcn(app, @TimeStepSliderValueChanged, true);
            app.TimeStepSlider.ValueChangingFcn = createCallbackFcn(app, @TimeStepSliderValueChanging, true);
            app.RunPanel = uipanel(app.GenerationTab);
            app.RunPanel.Title = 'Run';
            app.StartButton = uibutton(app.RunPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.trigFuncSelect = uiswitch(app.RunPanel, 'slider');
            app.trigFuncSelect.Items = {'Sound', 'SendT'};
            app.trigFuncSelect.Value = 'Sound';
            app.ShowCirclesCheckBox = uicheckbox(app.RunPanel);
            app.ShowCirclesCheckBox.Text = 'Show Circles';
            app.ShowCirclesCheckBox.Value = true;
            app.TrigandSoundCheckBox = uicheckbox(app.RunPanel);
            app.TrigandSoundCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrigandSoundCheckBoxValueChanged, true);
            app.TrigandSoundCheckBox.Text = 'Trig and Sound';
            app.SoundLengthEditFieldLabel = uilabel(app.RunPanel);
            app.SoundLengthEditFieldLabel.Text = 'SoundLength';
            app.SoundLengthEditField = uieditfield(app.RunPanel, 'numeric');
            app.SoundLengthEditField.Value = 0.05;
            app.CircleLifeEditFieldLabel = uilabel(app.RunPanel);
            app.CircleLifeEditFieldLabel.Text = 'CircleLife';
            app.CircleLifeEditField = uieditfield(app.RunPanel, 'numeric');
            app.CircleLifeEditField.ValueChangedFcn = createCallbackFcn(app, @CircleLifeEditFieldValueChanged, true);
            app.CircleLifeEditField.Value = 0.5;
            app.CircleDataFilesPanel = uipanel(app.GenerationTab);
            app.CircleDataFilesPanel.Title = 'Circle Data Files';
            app.LoadEditField = uieditfield(app.CircleDataFilesPanel, 'text');
            app.LoadEditField.Value = 'Circles.csv';
            app.LoadButton = uibutton(app.CircleDataFilesPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveEditField = uieditfield(app.CircleDataFilesPanel, 'text');
            app.SaveEditField.Value = 'Circles.csv';
            app.SaveButton = uibutton(app.CircleDataFilesPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CirclesTable = uitable(app.GenerationTab);
            app.CirclesTable.ColumnName = {'Centre X'; 'Centre Y'; 'Radius'; 'Color'; 'StartTime'; 'Trigger'};
            app.CirclesTable.RowName = {};
            app.CirclesTable.ColumnEditable = true;
            app.CirclesTable.CellEditCallback = createCallbackFcn(app, @CirclesTableCellEdit, true);
            app.AudioTab = uitab(app.TabGroup);
            app.AudioTab.Title = 'Audio';
            app.SubjectViewTab = uitab(app.TabGroup);
            app.SubjectViewTab.Title = 'Subject View';
            app.MainAxes = uiaxes(app.SubjectViewTab);
        end
    end

    methods (Access = public)

        % Construct app
        function app = circles

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end